classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlaySoundButton            matlab.ui.control.Button
        PlaymodifiedsoundButton    matlab.ui.control.Button
        NSliderLabel               matlab.ui.control.Label
        T60SliderLabel             matlab.ui.control.Label
        NSlider                    matlab.ui.control.Slider
        T60Slider                  matlab.ui.control.Slider
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        ArtificalReverbationLabel  matlab.ui.control.Label
        ImportButton               matlab.ui.control.Button
        FilenameEditFieldLabel     matlab.ui.control.Label
        FilenameEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        
       track_name;
       T60 = 2;         % Reverberation time -- higher value brings more echo ]1, 4[, for best results
       N = 16; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            [wav, fs] = audioread(app.track_name);
            soundsc(wav, fs);
            histogram(app.UIAxes, wav)
        end

        % Button pushed function: PlaymodifiedsoundButton
        function PlaymodifiedsoundButtonPushed(app, event)
            [wav, fs] = audioread(app.track_name);
                                        % number of interconnected delay lines
            A = eye(app.N);                         % feedback matrix equals to identity matrix
                                     
            g_dB = -60/(app.T60 * fs);
            g_lin = power(10, (g_dB/20));
            
            b_gains = rand(app.N,1);                % Randomised values between [0, 1] for input gains b_gains
            c_gains = rand(1, app.N);               % Randomised values between [0, 1] for output gains c_gains
            g_gains = ones(1, app.N);               % Setting all g_gain values to one
            m_delays = randi([500,2000],[1,app.N]); % For randomised delay values between [500, 2000]
            
           
            for i=1:16                          % g_gains calculated by given equation
                g_gains(i) = power(g_lin, m_delays(i));
            end
            
            y = FDN_func(wav, A, b_gains, c_gains, g_gains, m_delays);
            
            soundsc(y, fs)
            histogram(app.UIAxes, wav)
            histogram(app.UIAxes_2,y)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename, path] = uigetfile();
            app.FilenameEditField.Value = filename;
            app.track_name = filename;  
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end

        % Value changed function: T60Slider
        function T60SliderValueChanged(app, event)
            value = app.T60Slider.Value;
            app.T60 = value;
        end

        % Value changed function: NSlider
        function NSliderValueChanged(app, event)
            value = app.NSlider.Value;
            app.N = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.PlaymodifiedsoundButton = uibutton(app.UIFigure, 'push');
            app.PlaymodifiedsoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaymodifiedsoundButtonPushed, true);
            app.PlaymodifiedsoundButton.Text = 'Play modified sound';
            app.NSliderLabel = uilabel(app.UIFigure);
            app.NSliderLabel.Text = 'N';
            app.T60SliderLabel = uilabel(app.UIFigure);
            app.T60SliderLabel.Text = 'T60';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [1 24];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @NSliderValueChanged, true);
            app.NSlider.MinorTicks = [];
            app.NSlider.Value = 16;
            app.T60Slider = uislider(app.UIFigure);
            app.T60Slider.Limits = [1 4];
            app.T60Slider.MajorTicks = [1 2 3 4];
            app.T60Slider.ValueChangedFcn = createCallbackFcn(app, @T60SliderValueChanged, true);
            app.T60Slider.MinorTicks = [];
            app.T60Slider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original sound')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Modified sound')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ArtificalReverbationLabel = uilabel(app.UIFigure);
            app.ArtificalReverbationLabel.Text = 'Artifical Reverbation';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end